classdef Assist_Shortening_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        enabledatacollectionCheckBox   matlab.ui.control.CheckBox
        InitLowlevelcontrollerPanel    matlab.ui.container.Panel
        enablemotorvelocityRCheckBox   matlab.ui.control.CheckBox
        enabledriveRCheckBox           matlab.ui.control.CheckBox
        enablemotorvelocityLCheckBox   matlab.ui.control.CheckBox
        enabledriveLCheckBox           matlab.ui.control.CheckBox
        maxdorsiflexRButton            matlab.ui.control.Button
        maxplantarflexRButton          matlab.ui.control.Button
        zeroencoderRButton             matlab.ui.control.Button
        maxdorsiflexLButton            matlab.ui.control.Button
        maxplantarflexLButton          matlab.ui.control.Button
        zeroencoderLButton             matlab.ui.control.Button
        zeroloadcellRButton            matlab.ui.control.Button
        zeroLoadcellLButton            matlab.ui.control.Button
        SelectControllerDropDown       matlab.ui.control.DropDown
        SelectControllerDropDownLabel  matlab.ui.control.Label
        HighlevelcontrollerPanel       matlab.ui.container.Panel
        MinTorqueSlider                matlab.ui.control.Slider
        MinTorqueSliderLabel           matlab.ui.control.Label
        applyassistanceCheckBox        matlab.ui.control.CheckBox
        UploadMuscleParamsButton       matlab.ui.control.Button
        SelectMuscleParamsFileButton   matlab.ui.control.Button
        RightJointMoments              matlab.ui.control.UIAxes
        LeftJointMoments               matlab.ui.control.UIAxes
        RightMuscleMoments             matlab.ui.control.UIAxes
        LeftMuscleMoments              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Required inputs, might need to change
        readLength = 20 * 8;   % bytes, adjust to your read block
        adsPort = 350
        amsNetId = "172.18.234.64.1.1"  % replace % Description
        TwinCatAdsPath = 'C:\TwinCAT\AdsApi\.NET\v4.0.30319\TwinCAT.Ads.dll';
        sourceHandleName = 'Exosoft_Controller.Output.gui_out'
        sampling_frequency_gui = 10
        name_lowlevel_controller = 'my_lowlevel_controller'

        % variables specific for this program
        muscle_params_file = ''; % file with the calibrated muscle parameters
        headers_muscle_params = { ...
            'Gastroc_lmOpt', 'Gastroc_Atendon', 'Gastroc_tau_act', 'Gastroc_tau_deact', 'Gastroc_scale_emg', ...
            'Soleus_lmOpt', 'Soleus_Atendon', 'Soleus_tau_act', 'Soleus_tau_deact', 'Soleus_scale_emg', ...
            'Tibialis_lmOpt', 'Tibialis_Atendon', 'Tibialis_tau_act', 'Tibialis_tau_deact', 'Tibialis_scale_emg' ...
            }; % update muscle parameters will change these variables now

        % figure Left joint moment things
        leftJointPlot
        leftJointHandle
        leftJointStream
        leftJointBin

        leftJointModelPlot
        leftJointModelHandle
        leftJointModelStream
        leftJointModelBin

        leftJointStartTic
        leftJointWindowSec = 5
        leftJointMaxPoints = leftJointWindowSec * sampling_frequency_gui

        % figure Right joint moment things
        rightJointPlot
        rightJointHandle
        rightJointStream
        rightJointBin

        rightJointModelPlot
        rightJointModelHandle
        rightJointModelStream
        rightJointModelBin

        rightJointStartTic
        rightJointWindowSec = 5
        rightJointMaxPoints = rightJointWindowSec * sampling_frequency_gui


        % various things handled automatically
        tcClient
        sourceHandle
        readStream
        readBin
        writeMap struct
        loopTimer timer
    end

    methods (Access = private)

        function updateLoop(app)
            try
                app.tcClient.Read(app.sourceHandle, app.readStream);
                data = zeros(app.readLength/8,1);
                for i = 1:numel(data), data(i) = app.readBin.ReadDouble; end
                app.readStream.Position = 0;

                % -- update left joint moment datapoints
                app.tcClient.Read(app.leftJointHandle, app.leftJointStream);
                leftJointMoment = app.leftJointBin.ReadDouble;
                app.leftJointStream.Position = 0;
                app.tcClient.Read(app.leftJointModelHandle, app.leftJointModelStream);
                leftJointMomentModel = app.leftJointModelBin.ReadDouble;
                app.leftJointModelStream.Position = 0;

                % update right joint moment datapoints
                app.tcClient.Read(app.rightJointHandle, app.rightJointStream);
                rightJointMoment = app.rightJointBin.ReadDouble;
                app.rightJointStream.Position = 0;

                app.tcClient.Read(app.rightJointModelHandle, app.rightJointModelStream);
                rightJointMomentModel = app.rightJointModelBin.ReadDouble;
                app.rightJointModelStream.Position = 0;

            catch ME
                warning('TwinCAT update failed: %s');
                if ~isempty(app.loopTimer) && isvalid(app.loopTimer)
                    stop(app.loopTimer);
                end
                return;
            end

            app.updateLeftJointPlot(leftJointMoment, leftJointMomentModel);
            app.updateRightJointPlot(rightJointMoment, rightJointMomentModel);
        end

        function writeDouble(app, handle, value)
            app.tcClient.WriteAny(handle, typecast(double(value), 'uint8'));
        end

        function pulse(app, handle)
            app.writeDouble(handle, 1);
            pause(0.1);
            app.writeDouble(handle, 0);
        end

        function delete_ads_connection(app)
            if ~isempty(app.loopTimer) && isvalid(app.loopTimer), stop(app.loopTimer); delete(app.loopTimer); end
            if ~isempty(app.tcClient), app.tcClient.Close; end
        end

        % methods for graph
        function initLeftJointPlot(app)
            cla(app.LeftJointMoments);
            grid(app.LeftJointMoments, 'on');
            hold(app.LeftJointMoments, 'on');
            app.leftJointPlot = animatedline(app.LeftJointMoments, ...
                'Color', [0 0.4470 0.7410], ...
                'LineWidth', 1.25, ...
                'MaximumNumPoints', app.leftJointMaxPoints);
            app.leftJointModelPlot = animatedline(app.LeftJointMoments, ...
                'Color', [0.8500 0.3250 0.0980], ...
                'LineWidth', 1.25, ...
                'LineStyle', '--', ...
                'MaximumNumPoints', app.leftJointMaxPoints);
            legend(app.LeftJointMoments, {'Highlevel','Model'}, 'Location', 'best');
            app.leftJointStartTic = tic;
        end

        function updateLeftJointPlot(app, leftJointMoment, leftJointMomentModel)
            t = toc(app.leftJointStartTic);
            addpoints(app.leftJointPlot, t, leftJointMoment);
            addpoints(app.leftJointModelPlot, t, leftJointMomentModel);
            if t > app.leftJointWindowSec
                xlim(app.LeftJointMoments, [t - app.leftJointWindowSec, t]);
            else
                xlim(app.LeftJointMoments, [0, app.leftJointWindowSec]);
            end
            drawnow limitrate nocallbacks
        end

        function initRightJointPlot(app)
            cla(app.RightJointMoments);
            grid(app.RightJointMoments, 'on');
            hold(app.RightJointMoments, 'on');
            app.rightJointPlot = animatedline(app.RightJointMoments, ...
                'Color', [0 0.4470 0.7410], ...
                'LineWidth', 1.25, ...
                'MaximumNumPoints', app.rightJointMaxPoints);
            app.rightJointModelPlot = animatedline(app.RightJointMoments, ...
                'Color', [0.8500 0.3250 0.0980], ...
                'LineWidth', 1.25, ...
                'LineStyle', '--', ...
                'MaximumNumPoints', app.rightJointMaxPoints);
            legend(app.RightJointMoments, {'Highlevel','Model'}, 'Location', 'best');
            app.rightJointStartTic = tic;
        end

        function updateRightJointPlot(app, rightJointMoment, rightJointMomentModel)
            t = toc(app.rightJointStartTic);
            addpoints(app.rightJointPlot, t, rightJointMoment);
            addpoints(app.rightJointModelPlot, t, rightJointMomentModel);
            if t > app.rightJointWindowSec
                xlim(app.RightJointMoments, [t - app.rightJointWindowSec, t]);
            else
                xlim(app.RightJointMoments, [0, app.rightJointWindowSec]);
            end
            drawnow limitrate nocallbacks
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this is the startup function of the GUI
            % ponytail: delete old timers from previous crashed runs; narrow to app-owned timer if needed later
            t = timerfindall;
            if ~isempty(t), delete(t); end


            % add twincat Ads
            NET.addAssembly(app.TwinCatAdsPath);
            import TwinCAT.Ads.*

            app.tcClient = TcAdsClient;
            app.tcClient.Connect(AmsNetId(char(app.amsNetId)), app.adsPort);

            app.sourceHandle = app.tcClient.CreateVariableHandle(app.sourceHandleName); % replace
            app.readStream = AdsStream(app.readLength);
            app.readBin = AdsBinaryReader(app.readStream);


            % init left joint moment plot
            %           -- desired exoskeleton moment
            app.leftJointHandle = app.tcClient.CreateVariableHandle( ...
                'jlo_assist_short_explicit_clean_Lonit.Output.exo_torque_desired_highlevel_l');
            app.leftJointStream = AdsStream(8);
            app.leftJointBin = AdsBinaryReader(app.leftJointStream);
            %           -- desired moment from muscle model
            app.leftJointModelHandle = app.tcClient.CreateVariableHandle( ...
                'jlo_assist_short_explicit_clean_Lonit.Output.exo_torque_desired_l');
            app.leftJointModelStream = AdsStream(8);
            app.leftJointModelBin = AdsBinaryReader(app.leftJointModelStream);

            % lets start with the muscle parameters. note that we will have
            % to change this for the left and right side
            app.writeMap = struct( ...
                'Gastroc_lmOpt',     app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Gastroc_lmOpt'), ...
                'Gastroc_Atendon',   app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Gastroc_Atendon'), ...
                'Gastroc_tau_act',   app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Gastroc_tau_act'), ...
                'Gastroc_tau_deact', app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Gastroc_tau_deact'), ...
                'Gastroc_scale_emg', app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Gastroc_scale_emg'), ...
                'Soleus_lmOpt',      app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Soleus_lmOpt'), ...
                'Soleus_Atendon',    app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Soleus_Atendon'), ...
                'Soleus_tau_act',    app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Soleus_tau_act'), ...
                'Soleus_tau_deact',  app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Soleus_tau_deact'), ...
                'Soleus_scale_emg',  app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Soleus_scale_emg'), ...
                'Tibialis_lmOpt',    app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Tibialis_lmOpt'), ...
                'Tibialis_Atendon',  app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Tibialis_Atendon'), ...
                'Tibialis_tau_act',  app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Tibialis_tau_act'), ...
                'Tibialis_tau_deact',app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Tibialis_tau_deact'), ...
                'Tibialis_scale_emg',app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.Tibialis_scale_emg') ...
                ); % add all required variables

            % controller decision logic
            app.writeMap.ControllerMode = app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.ControllerMode');
            app.writeMap.MinimalTorque = app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.MinimalTorque');
            app.writeMap.ApplyAssistance = app.tcClient.CreateVariableHandle('jlo_assist_short_explicit_clean_Lonit.ModelParameters.ApplyAssistance');


            % low level controller parameters
            base_name = [app.name_lowlevel_controller '.ModelParameters.'];
            app.writeMap.zero_loadcell_left = app.tcClient.CreateVariableHandle([base_name, 'Constant50']);
            app.writeMap.zero_loadcell_right = app.tcClient.CreateVariableHandle([base_name, 'Constant64']);
            app.writeMap.zero_encoder_left = app.tcClient.CreateVariableHandle([base_name, 'Constant30']);
            app.writeMap.zero_encoder_right = app.tcClient.CreateVariableHandle([base_name, 'Constant65']);
            app.writeMap.max_plantarflex_left = app.tcClient.CreateVariableHandle([base_name, 'Constant51']);
            app.writeMap.max_plantarflex_right = app.tcClient.CreateVariableHandle([base_name, 'Constant34']);
            app.writeMap.max_dorsiflex_left = app.tcClient.CreateVariableHandle([base_name, 'Constant35']);
            app.writeMap.max_dorsiflex_right = app.tcClient.CreateVariableHandle([base_name, 'Constant36']);
            app.writeMap.max_torque = app.tcClient.CreateVariableHandle([base_name, 'Constant40']);
            app.writeMap.enable_drives_left = app.tcClient.CreateVariableHandle([base_name, 'Constant33']);
            app.writeMap.enable_drives_right = app.tcClient.CreateVariableHandle([base_name, 'Constant54']);
            app.writeMap.enable_motor_velocity_left = app.tcClient.CreateVariableHandle([base_name, 'Constant25']);
            app.writeMap.enable_motor_velocity_right = app.tcClient.CreateVariableHandle([base_name, 'Constant26']);
            app.writeMap.enable_data_collection = app.tcClient.CreateVariableHandle([base_name, 'Constant4']);
            
            % init the plot
            app.initLeftJointPlot();
            app.initRightJointPlot();

            % set sampling frequency of the GUI
            app.loopTimer = timer('ExecutionMode','fixedRate','Period',1/app.sampling_frequency_gui, ...
                'TimerFcn', @(~,~)app.updateLoop());
            start(app.loopTimer);

        end

        % Button pushed function: SelectMuscleParamsFileButton
        function SelectMuscleParamsFileButtonPushed(app, event)
            % this callback should open a window that enables the user
            % to select the matlab file you want to apply in the real-time
            % controller
            [file, path] = uigetfile('*.mat', 'Select a MATLAB file');
            app.muscle_params_file = fullfile(path, file);
            if isequal(file, 0)
                disp('No file selected.');
            else
                fullFileName = fullfile(path, file);
                fprintf('Selected file: %s\n', fullFileName);
            end
        end

        % Button pushed function: UploadMuscleParamsButton
        function UploadMuscleParamsButtonPushed(app, event)
            % this callback should upload the muscle parameters to twincat
            % steps:
            %   1) read them from .mat file
            %   2) assign them to twincat constants

            if exist(app.muscle_params_file,'file')
                % load the muscle params file
                muscle_params = load(app.muscle_params_file);
                % unpack muscle params in memory
                % ToDo: discuss how to do this with Lonit. This will
                % look something like this
                % unpack the model: this extracts all the params to the
                % workspace
                %unpack_model(muscle_params.model)
                % loop over all params we want to change
                for iparam = 1:length(app.headers_muscle_params)
                    % name of the variable
                    var_name = app.headers_muscle_params{iparam};
                    % the muscle name and param name
                    parts = regexp(var_name, '^([^_]+)_(.*)$', 'tokens', 'once');
                    muscle_name = parts{1};
                    param_name = parts{2};
                    % adapt muscle parameter value
                    app.writeDouble(app.writeMap.(var_name),...
                        muscle_params.model.(muscle_name).(param_name));
                end
            else
                disp([app.muscle_params_file ' does not exist']);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete_ads_connection(app)
            delete(app)

        end

        % Value changed function: SelectControllerDropDown
        function SelectControllerDropDownValueChanged(app, event)
            value = app.SelectControllerDropDown.Value;
            % I guess that value is now a string with the name that is
            % selected. the options are
            % {'Minimal_Impedance', 'BiologicalMoment', 'AssistShortening'}
            controller_value = 0; % default is minimal impdance
            if strcmp(value,'Minimal_Impedance')
                controller_value = 0;
            elseif strcmp(value,'BiologicalMoment')
                controller_value = 1;
            elseif strcmp(value,'AssistShortening')
                controller_value = 2;
            end
            app.writeDouble(app.writeMap.ControllerMode,controller_value);
        end

        % Value changed function: MinTorqueSlider
        function MinTorqueSliderValueChanged(app, event)
            % set the minimal torque
            value = app.MinTorqueSlider.Value;
            app.writeDouble(app.writeMap.MinimalTorque,value);

        end

        % Value changed function: applyassistanceCheckBox
        function applyassistanceCheckBoxValueChanged(app, event)
            bool_assistance = app.applyassistanceCheckBox.Value;
            if bool_assistance
                app.writeDouble(app.writeMap.ApplyAssistance, 1)
            else
                app.writeDouble(app.writeMap.ApplyAssistance, 0)
            end
        end

        % Button pushed function: zeroLoadcellLButton
        function zeroLoadcellLButtonPushed(app, event)
            disp('button pushed')
        end

        % Button pushed function: zeroloadcellRButton
        function zeroloadcellRButtonPushed(app, event)
            disp('button pushed')
        end

        % Button pushed function: zeroencoderLButton
        function zeroencoderLButtonPushed(app, event)
            disp('button pushed')

        end

        % Button pushed function: zeroencoderRButton
        function zeroencoderRButtonPushed(app, event)
            disp('button pushed')

        end

        % Button pushed function: maxplantarflexLButton
        function maxplantarflexLButtonPushed(app, event)
            disp('button pushed')

        end

        % Button pushed function: maxplantarflexRButton
        function maxplantarflexRButtonPushed(app, event)
            disp('button pushed')

        end

        % Button pushed function: maxdorsiflexLButton
        function maxdorsiflexLButtonPushed(app, event)
            disp('button pushed')

        end

        % Button pushed function: maxdorsiflexRButton
        function maxdorsiflexRButtonPushed(app, event)
            disp('button pushed')

        end

        % Value changed function: enabledriveLCheckBox
        function enabledriveLCheckBoxValueChanged(app, event)
            value = app.enabledriveLCheckBox.Value;
            disp(value);
        end

        % Value changed function: enabledriveRCheckBox
        function enabledriveRCheckBoxValueChanged(app, event)
            value = app.enabledriveRCheckBox.Value;
            disp(value);
        end

        % Value changed function: enablemotorvelocityLCheckBox
        function enablemotorvelocityLCheckBoxValueChanged(app, event)
            value = app.enablemotorvelocityLCheckBox.Value;
            disp(value);
        end

        % Value changed function: enablemotorvelocityRCheckBox
        function enablemotorvelocityRCheckBoxValueChanged(app, event)
            value = app.enablemotorvelocityRCheckBox.Value;
            disp(value);
        end

        % Value changed function: enabledatacollectionCheckBox
        function enabledatacollectionCheckBoxValueChanged(app, event)
            value = app.enabledatacollectionCheckBox.Value;
            print(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftMuscleMoments = uiaxes(app.UIFigure);
            title(app.LeftMuscleMoments, 'Left')
            ylabel(app.LeftMuscleMoments, 'Muscle Moment')
            zlabel(app.LeftMuscleMoments, 'Z')
            app.RightMuscleMoments = uiaxes(app.UIFigure);
            title(app.RightMuscleMoments, 'Right')
            ylabel(app.RightMuscleMoments, 'Muscle Moment')
            zlabel(app.RightMuscleMoments, 'Z')
            app.LeftJointMoments = uiaxes(app.UIFigure);
            xlabel(app.LeftJointMoments, 'Time [s]')
            ylabel(app.LeftJointMoments, 'Ankle Moment')
            zlabel(app.LeftJointMoments, 'Z')
            app.RightJointMoments = uiaxes(app.UIFigure);
            xlabel(app.RightJointMoments, 'Time [s]')
            ylabel(app.RightJointMoments, 'Ankle Moment')
            zlabel(app.RightJointMoments, 'Z')
            app.HighlevelcontrollerPanel = uipanel(app.UIFigure);
            app.HighlevelcontrollerPanel.Title = 'High level controller';
            app.SelectMuscleParamsFileButton = uibutton(app.HighlevelcontrollerPanel, 'push');
            app.SelectMuscleParamsFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMuscleParamsFileButtonPushed, true);
            app.SelectMuscleParamsFileButton.Text = 'Select Muscle Params File';
            app.UploadMuscleParamsButton = uibutton(app.HighlevelcontrollerPanel, 'push');
            app.UploadMuscleParamsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMuscleParamsButtonPushed, true);
            app.UploadMuscleParamsButton.Text = 'Upload Muscle Params';
            app.applyassistanceCheckBox = uicheckbox(app.HighlevelcontrollerPanel);
            app.applyassistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @applyassistanceCheckBoxValueChanged, true);
            app.applyassistanceCheckBox.Text = 'apply assistance';
            app.MinTorqueSliderLabel = uilabel(app.HighlevelcontrollerPanel);
            app.MinTorqueSliderLabel.Text = 'Min Torque';
            app.MinTorqueSlider = uislider(app.HighlevelcontrollerPanel);
            app.MinTorqueSlider.Limits = [0 10];
            app.MinTorqueSlider.ValueChangedFcn = createCallbackFcn(app, @MinTorqueSliderValueChanged, true);
            app.MinTorqueSlider.Step = 1;
            app.MinTorqueSlider.Value = 2;
            app.SelectControllerDropDownLabel = uilabel(app.UIFigure);
            app.SelectControllerDropDownLabel.Text = 'Select Controller';
            app.SelectControllerDropDown = uidropdown(app.UIFigure);
            app.SelectControllerDropDown.Items = {'Minimal_Impedance', 'BiologicalMoment', 'AssistShortening'};
            app.SelectControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectControllerDropDownValueChanged, true);
            app.SelectControllerDropDown.Value = 'Minimal_Impedance';
            app.InitLowlevelcontrollerPanel = uipanel(app.UIFigure);
            app.InitLowlevelcontrollerPanel.Title = 'Init Lowlevel controller';
            app.zeroLoadcellLButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.zeroLoadcellLButton.ButtonPushedFcn = createCallbackFcn(app, @zeroLoadcellLButtonPushed, true);
            app.zeroLoadcellLButton.Text = 'zero Loadcell  L';
            app.zeroloadcellRButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.zeroloadcellRButton.ButtonPushedFcn = createCallbackFcn(app, @zeroloadcellRButtonPushed, true);
            app.zeroloadcellRButton.Text = 'zero loadcell  R';
            app.zeroencoderLButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.zeroencoderLButton.ButtonPushedFcn = createCallbackFcn(app, @zeroencoderLButtonPushed, true);
            app.zeroencoderLButton.Text = 'zero encoder L';
            app.maxplantarflexLButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.maxplantarflexLButton.ButtonPushedFcn = createCallbackFcn(app, @maxplantarflexLButtonPushed, true);
            app.maxplantarflexLButton.Text = 'max plantarflex L';
            app.maxdorsiflexLButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.maxdorsiflexLButton.ButtonPushedFcn = createCallbackFcn(app, @maxdorsiflexLButtonPushed, true);
            app.maxdorsiflexLButton.Text = 'max dorsiflex L';
            app.zeroencoderRButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.zeroencoderRButton.ButtonPushedFcn = createCallbackFcn(app, @zeroencoderRButtonPushed, true);
            app.zeroencoderRButton.Text = 'zero encoder R';
            app.maxplantarflexRButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.maxplantarflexRButton.ButtonPushedFcn = createCallbackFcn(app, @maxplantarflexRButtonPushed, true);
            app.maxplantarflexRButton.Text = 'max plantarflex R';
            app.maxdorsiflexRButton = uibutton(app.InitLowlevelcontrollerPanel, 'push');
            app.maxdorsiflexRButton.ButtonPushedFcn = createCallbackFcn(app, @maxdorsiflexRButtonPushed, true);
            app.maxdorsiflexRButton.Text = 'max dorsiflex R';
            app.enabledriveLCheckBox = uicheckbox(app.InitLowlevelcontrollerPanel);
            app.enabledriveLCheckBox.ValueChangedFcn = createCallbackFcn(app, @enabledriveLCheckBoxValueChanged, true);
            app.enabledriveLCheckBox.Text = 'enable drive L';
            app.enablemotorvelocityLCheckBox = uicheckbox(app.InitLowlevelcontrollerPanel);
            app.enablemotorvelocityLCheckBox.ValueChangedFcn = createCallbackFcn(app, @enablemotorvelocityLCheckBoxValueChanged, true);
            app.enablemotorvelocityLCheckBox.Text = 'enable motor velocity L';
            app.enabledriveRCheckBox = uicheckbox(app.InitLowlevelcontrollerPanel);
            app.enabledriveRCheckBox.ValueChangedFcn = createCallbackFcn(app, @enabledriveRCheckBoxValueChanged, true);
            app.enabledriveRCheckBox.Text = 'enable drive R';
            app.enablemotorvelocityRCheckBox = uicheckbox(app.InitLowlevelcontrollerPanel);
            app.enablemotorvelocityRCheckBox.ValueChangedFcn = createCallbackFcn(app, @enablemotorvelocityRCheckBoxValueChanged, true);
            app.enablemotorvelocityRCheckBox.Text = 'enable motor velocity R';
            app.enabledatacollectionCheckBox = uicheckbox(app.UIFigure);
            app.enabledatacollectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @enabledatacollectionCheckBoxValueChanged, true);
            app.enabledatacollectionCheckBox.Text = 'enable data collection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assist_Shortening_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end